classdef NumberQuest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CURRENTROUNDNOEditField       matlab.ui.control.NumericEditField
        CURRENTROUNDNOEditFieldLabel  matlab.ui.control.Label
        QUITTHEGAMEButton             matlab.ui.control.Button
        STARTTHENEWROUNDButton        matlab.ui.control.Button
        MYSCOREEditField              matlab.ui.control.NumericEditField
        MYSCOREEditFieldLabel         matlab.ui.control.Label
        YOURSCOREEditField            matlab.ui.control.NumericEditField
        YOURSCOREEditFieldLabel       matlab.ui.control.Label
        MYGUESSEditField              matlab.ui.control.NumericEditField
        MYGUESSEditFieldLabel         matlab.ui.control.Label
        STARTTHEGAMEButton            matlab.ui.control.Button
        ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField  matlab.ui.control.NumericEditField
        ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditFieldLabel  matlab.ui.control.Label
        SUBMITTHEGUESSButton          matlab.ui.control.Button
        ENTERYOURGUESSEditField       matlab.ui.control.NumericEditField
        ENTERYOURGUESSLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        UserScore = 0; % Initialize UserScore
        SystemScore = 0; % Initialize SystemScore
        MaxRounds=0;
        combinedMessage = ''; % Initialize combinedMessage
    end

   methods (Access = public)
        % Function to start a new round
        function StartNewRound(app)
             app.ENTERYOURGUESSEditField.Value = [];
             app.MYGUESSEditField.Value = [];
        end
   end
    
    methods (Access = private)
        
        function Max(app)
            if app.CURRENTROUNDNOEditField.Value > app.MaxRounds
                % Check the winner
                if app.UserScore > app.SystemScore
                    winnerMessage = 'Congratulations! You are the winner!';
                elseif app.UserScore < app.SystemScore
                    winnerMessage = 'Sorry, I am the winner!';
                else
                    winnerMessage = 'It is a tie! Both you and I are winners!';
                end

                % Display a message or take appropriate action when the game is over
                farewellMessage = 'Thanks for playing! Goodbye!';
                app.combinedMessage = sprintf('%s\n\nYour Score: %d\nMy Score: %d\n\n%s', farewellMessage, app.UserScore, app.SystemScore, winnerMessage);
                msgbox(app.combinedMessage, 'Game Over', 'modal'); % Replace with your logic

                delete(app.UIFigure);
                % You might want to disable certain UI elements or perform other actions
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTTHEGAMEButton
        function STARTTHEGAMEButtonPushed(app, event)
   % 1. Get the number of rounds from the user
    numRounds= app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField.Value;

 % 2. Initialize game variables
    app.CURRENTROUNDNOEditField.Value = 1; % Start from round 1
    app.YOURSCOREEditField.Value = 0; % Initialize user score
    app.MYSCOREEditField.Value = 0; % Initialize system score
    app.ENTERYOURGUESSEditField.Value=[];
    app.MYGUESSEditField.Value=[];

    % Add any other initialization steps as needed

    % 3. Update the UI to reflect the start of the game
    app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField.Enable = 'off'; % Disable entering rounds
    app.ENTERYOURGUESSEditField.Enable = 'on'; % Enable entering guesses
    app.SUBMITTHEGUESSButton.Enable = 'on'; % Enable submitting guesses
    app.STARTTHENEWROUNDButton.Enable = 'on'; % Enable starting new rounds
    app.QUITTHEGAMEButton.Enable = 'on'; % Enable quitting the game
    % You can add additional UI updates here

    % Display a message indicating the start of the game
    %uialert(app.UIFigure, 'Game started! Good luck!', 'Game Info', 'Icon', 'info');
    app.MaxRounds = numRounds-1;


        end

        % Button pushed function: SUBMITTHEGUESSButton
        function SUBMITTHEGUESSButtonPushed(app, event)
          % Get the user's guess from the input field
 numRounds= app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField.Value;
 app.MaxRounds =numRounds;
userGuess = app.ENTERYOURGUESSEditField.Value;
% Generate a random system guess (replace this with your logic)
systemGuess = randi([0, 200]);
% Display the system's guess in the corresponding field
app.MYGUESSEditField.Value = systemGuess;

% Compare the user's guess with the system's guess and update scores
if userGuess > systemGuess
    % User wins, update user's score
    app.UserScore = app.UserScore + 1;
elseif userGuess < systemGuess
    % System wins, update system's score
    app.SystemScore = app.SystemScore + 1;
else
    % It's a tie, both get one point
    app.UserScore = app.UserScore + 1;
    app.SystemScore = app.SystemScore + 1;
end

% Update the round number
app.CURRENTROUNDNOEditField.Value = app.CURRENTROUNDNOEditField.Value + 1;

% Update the display of scores
app.YOURSCOREEditField.Value = app.UserScore;
app.MYSCOREEditField.Value = app.SystemScore;
pause(2);
app.ENTERYOURGUESSEditField.Value=[];
app.MYGUESSEditField.Value=[];
app.Max();
% Optionally, perform additional actions based on the game logic
% ...
    % ...

    % Optionally, allow the user to start a new round or end the game
    % ...
        end

        % Button pushed function: STARTTHENEWROUNDButton
        function STARTTHENEWROUNDButtonPushed(app, event)
       app.StartNewRound();
    app.CURRENTROUNDNOEditField.Value = 1; % Start from round 1
    app.YOURSCOREEditField.Value = 0; % Initialize user score
    app.MYSCOREEditField.Value = 0; % Initialize system score

    % Optionally, add additional logic for starting a new round here
    % This might include generating a new system guess, resetting counters, etc.

        end

        % Button pushed function: QUITTHEGAMEButton
        function QUITTHEGAMEButtonPushed(app, event)
           userResponse = questdlg('Are you sure you want to quit the game?','Quit Game', 'Yes', 'No', 'No');

    % Check the user's response
    if strcmp(userResponse, 'Yes')
        % Add any additional logic you want to execute before quitting
        if app.UserScore > app.SystemScore
            winnerMessage = 'Congratulations! You are the winner!';
        elseif app.UserScore < app.SystemScore
            winnerMessage = 'Sorry, I am the winner!';
        else
            winnerMessage = 'It is a tie! Both you and me are winners!';
        end

        % Display a farewell message along with the winner message
        farewellMessage = 'Thanks for playing! Goodbye!';
        app.combinedMessage = sprintf('%s\n\n%s', farewellMessage, winnerMessage);
        msgbox(app.combinedMessage, 'Game Over', 'modal');
    end
    delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ENTERYOURGUESSLabel = uilabel(app.UIFigure);
            app.ENTERYOURGUESSLabel.Text = 'ENTER YOUR GUESS ';
            app.ENTERYOURGUESSEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENTERYOURGUESSEditField.Limits = [0 200];
            app.ENTERYOURGUESSEditField.RoundFractionalValues = 'on';
            app.ENTERYOURGUESSEditField.AllowEmpty = 'on';
            app.ENTERYOURGUESSEditField.Value = [];
            app.SUBMITTHEGUESSButton = uibutton(app.UIFigure, 'push');
            app.SUBMITTHEGUESSButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITTHEGUESSButtonPushed, true);
            app.SUBMITTHEGUESSButton.Text = 'SUBMIT THE GUESS';
            app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditFieldLabel = uilabel(app.UIFigure);
            app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditFieldLabel.Text = ' ENTER THE NUMBER OF ROUNDS YOU WANT TO PLAY';
            app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField.Limits = [1 Inf];
            app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField.RoundFractionalValues = 'on';
            app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField.AllowEmpty = 'on';
            app.ENTERTHENUMBEROFROUNDSYOUWANTTOPLAYEditField.Value = [];
            app.STARTTHEGAMEButton = uibutton(app.UIFigure, 'push');
            app.STARTTHEGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTHEGAMEButtonPushed, true);
            app.STARTTHEGAMEButton.Text = 'START THE GAME';
            app.MYGUESSEditFieldLabel = uilabel(app.UIFigure);
            app.MYGUESSEditFieldLabel.Text = 'MY GUESS';
            app.MYGUESSEditField = uieditfield(app.UIFigure, 'numeric');
            app.MYGUESSEditField.RoundFractionalValues = 'on';
            app.MYGUESSEditField.AllowEmpty = 'on';
            app.MYGUESSEditField.Value = [];
            app.YOURSCOREEditFieldLabel = uilabel(app.UIFigure);
            app.YOURSCOREEditFieldLabel.Text = 'YOUR SCORE';
            app.YOURSCOREEditField = uieditfield(app.UIFigure, 'numeric');
            app.YOURSCOREEditField.AllowEmpty = 'on';
            app.YOURSCOREEditField.Value = [];
            app.MYSCOREEditFieldLabel = uilabel(app.UIFigure);
            app.MYSCOREEditFieldLabel.Text = 'MY SCORE';
            app.MYSCOREEditField = uieditfield(app.UIFigure, 'numeric');
            app.MYSCOREEditField.AllowEmpty = 'on';
            app.MYSCOREEditField.Value = [];
            app.STARTTHENEWROUNDButton = uibutton(app.UIFigure, 'push');
            app.STARTTHENEWROUNDButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTHENEWROUNDButtonPushed, true);
            app.STARTTHENEWROUNDButton.Text = 'START THE NEW ROUND';
            app.QUITTHEGAMEButton = uibutton(app.UIFigure, 'push');
            app.QUITTHEGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @QUITTHEGAMEButtonPushed, true);
            app.QUITTHEGAMEButton.Text = 'QUIT THE GAME';
            app.CURRENTROUNDNOEditFieldLabel = uilabel(app.UIFigure);
            app.CURRENTROUNDNOEditFieldLabel.Text = 'CURRENT ROUND NO.';
            app.CURRENTROUNDNOEditField = uieditfield(app.UIFigure, 'numeric');
            app.CURRENTROUNDNOEditField.Limits = [1 Inf];
            app.CURRENTROUNDNOEditField.AllowEmpty = 'on';
            app.CURRENTROUNDNOEditField.Value = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NumberQuest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end